classdef ATc_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ParametersFunctionsTab          matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        betapromoterstrenthEditFieldLabel  matlab.ui.control.Label
        betapromoterstrenthEditField    matlab.ui.control.NumericEditField
        KxdiffusionrateofATcEditFieldLabel  matlab.ui.control.Label
        KxdiffusionrateofATcEditField   matlab.ui.control.NumericEditField
        KddiffusionrateoftetREditFieldLabel  matlab.ui.control.Label
        KddiffusionrateoftetREditField  matlab.ui.control.NumericEditField
        Ktrans_tetRtranslationrateoftetREditFieldLabel  matlab.ui.control.Label
        Ktrans_tetRtranslationrateoftetREditField  matlab.ui.control.NumericEditField
        Kdeg_tetRdegradationrateoftetREditFieldLabel  matlab.ui.control.Label
        Kdeg_tetRdegradationrateoftetREditField  matlab.ui.control.NumericEditField
        Kdeg_mRNAdegradationrateofmRNAEditFieldLabel  matlab.ui.control.Label
        Kdeg_mRNAdegradationrateofmRNAEditField  matlab.ui.control.NumericEditField
        PtetcopynumberEditFieldLabel    matlab.ui.control.Label
        PtetcopynumberEditField         matlab.ui.control.NumericEditField
        ATc_minEditFieldLabel           matlab.ui.control.Label
        ATc_minEditField                matlab.ui.control.NumericEditField
        ATc_maxEditFieldLabel           matlab.ui.control.Label
        ATc_maxEditField                matlab.ui.control.NumericEditField
        StartButton                     matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        Kdeg_ATcdegradationrateofATcEditFieldLabel  matlab.ui.control.Label
        Kdeg_ATcdegradationrateofATcEditField  matlab.ui.control.NumericEditField
        Ktrans_GSDMDtranslationrateofGSDMDEditFieldLabel  matlab.ui.control.Label
        Ktrans_GSDMDtranslationrateofGSDMDEditField  matlab.ui.control.NumericEditField
        Kdeg_GSDMDdegradationrateofGSDMDEditFieldLabel  matlab.ui.control.Label
        Kdeg_GSDMDdegradationrateofGSDMDEditField  matlab.ui.control.NumericEditField
        InitialODofbacteriaEditFieldLabel  matlab.ui.control.Label
        InitialODofbacteriaEditField    matlab.ui.control.NumericEditField
        Growthrateofbacteria1minEditFieldLabel  matlab.ui.control.Label
        Growthrateofbacteria1minEditField  matlab.ui.control.NumericEditField
        MaxODofbacteriaEditFieldLabel   matlab.ui.control.Label
        MaxODofbacteriaEditField        matlab.ui.control.NumericEditField
        BaceteriasamountperOD1010EditFieldLabel  matlab.ui.control.Label
        BaceteriasamountperOD1010EditField  matlab.ui.control.NumericEditField
        Averragebacteriasvolumem3EditFieldLabel  matlab.ui.control.Label
        Averragebacteriasvolumem3EditField  matlab.ui.control.NumericEditField
        Panel3                          matlab.ui.container.Panel
        FunctionPredictedLabel          matlab.ui.control.Label
        NALabel                         matlab.ui.control.Label
        Panel4                          matlab.ui.container.Panel
        NoticeTextAreaLabel             matlab.ui.control.Label
        NoticeTextArea                  matlab.ui.control.TextArea
        FiguresTab                      matlab.ui.container.Tab
        Panel2                          matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        ATcModellingSoftwareLabel       matlab.ui.control.Label
    end


    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % 原始方程
            % Xact为激活的tetR浓度，Xt为tetR浓度，Sx为aTC浓度
            % (Xact) = (Xt)/(1+(Sx/Kx)^n);
            % (A_mRNA) = (Ptet)*(beta)/(1+Xact/Kd); mRNA转录速率
            % (dmRNA)/dt = (A_mRNA)-(Kdeg_mRNA)*(mRNA); mRNA累积速率
            % (dtetR)/dt = (Ktrans)*mRNA-(Kdeg_tetR)*(tetR); tetR累积速率
            % aTC为aTC浓度, beta为Ptet启动子启动速率, Kx为aTC与tetR的解离常数, Kd为tetR与DNA的解离常数
            % Ktrans为mRNA转录速率, Kdeg_mRNA为mRNA降解速率, Kdeg_tetR为tetR降解速率, Ptet为启动子拷贝数
            % ln(aTC(t))=ln(aTC(0))-kdeg_atc*t; aTC降解，kdeg_atc为atc降解速率常数
            
            %参数设定
            
            beta=app.betapromoterstrenthEditField.Value;
            Kx=app.KxdiffusionrateofATcEditField.Value;
            Kd=app.KddiffusionrateoftetREditField.Value;
            Ktrans=app.Ktrans_tetRtranslationrateoftetREditField.Value;
            Kdeg_mRNA=app.Kdeg_mRNAdegradationrateofmRNAEditField.Value;
            Kdeg_tetR=app.Kdeg_tetRdegradationrateoftetREditField.Value;
            Ptet=app.PtetcopynumberEditField.Value;
            atc_down=app.ATc_minEditField.Value;
            atc_up=app.ATc_maxEditField.Value;
            Kdeg_atc=app.Kdeg_ATcdegradationrateofATcEditField.Value;
            Ktrans_GSDMD=app.Ktrans_GSDMDtranslationrateofGSDMDEditField.Value;
            Kdeg_GSDMD=app.Kdeg_GSDMDdegradationrateofGSDMDEditField.Value;
            n_bacteria=app.InitialODofbacteriaEditField.Value;
            r_bacteria=app.Growthrateofbacteria1minEditField.Value;
            K_max=app.MaxODofbacteriaEditField.Value;
            OD_amount=app.BaceteriasamountperOD1010EditField.Value;
            v_bacteria=app.Averragebacteriasvolumem3EditField.Value;
            
            TIME=0:300;
            i=1;
            insight=zeros(1,int16((atc_up-atc_down)/0.5+1));
            aTC_Con=zeros(1,int16((atc_up-atc_down)/0.5+1));
            hold(app.UIAxes);
            hold(app.UIAxes_3);
            
            for aTC=atc_down:0.5:atc_up
                aTC_Con(i)=aTC;
                insight(i)=parameter(aTC, beta, Kx, Kd, Ktrans, Kdeg_mRNA, Kdeg_tetR, Ptet, Ktrans_GSDMD, Kdeg_GSDMD, Kdeg_atc, n_bacteria, r_bacteria, K_max, OD_amount, v_bacteria);
                i=i+1;
            end
  
            
            plot(app.UIAxes_2, aTC_Con, insight, 'red', 'linewidth', 2);
            hold(app.UIAxes_2);
            plot(app.UIAxes_4, TIME, K_max./(1+(K_max/n_bacteria-1)*exp(-TIME./r_bacteria)), 'blue', 'linewidth',2);
            [p,s]=polyfit(aTC_Con, insight,2);
%             y_anticipate=p(1).*(aTC_Con).^2+p(2).*aTC_Con+p(3);
            app.NALabel.Text=sprintf('GSDMD=(%f*ATc^2 + %f * ATc + %f)', p(1), p(2), p(3));
%             plot(app.UIAxes_2, aTC_Con, y_anticipate);
            
            
            %带入常量解微分方程，并绘制动力学图像
            function equali=parameter(aTC, beta, Kx, Kd, Ktrans, Kdeg_mRNA, Kdeg_tetR, Ptet,  Ktrans_GSDMD, Kdeg_GSDMD, Kdeg_atc, n_bacteria, r_bacteria, K_max, OD_amount, v_bacteria)
                [t,y]=ode45(@odefun,[0,30000],[0,0,0],[],aTC, beta, Kx, Kd, Ktrans, Kdeg_mRNA, Kdeg_tetR, Ptet, Ktrans_GSDMD, Kdeg_GSDMD, Kdeg_atc, n_bacteria, r_bacteria, K_max, OD_amount, v_bacteria);                
                plot(app.UIAxes,t,y(:,2),'cyan')
                plot(app.UIAxes_3,t,y(:,3),'green')                             
                equali=max(y(:,3));
            end
            
            %微分方程组
            function dydt=odefun(t,y,aTC, beta, Kx, Kd, Ktrans, Kdeg_mRNA, Kdeg_tetR, Ptet, Ktrans_GSDMD, Kdeg_GSDMD, Kdeg_atc, n_bacteria, r_bacteria, K_max, OD_amount, v_bacteria)
                dydt=zeros(3,1);
                %y1是mRNA转录量，y2是tetR转录量
                dydt(1)=(Ptet*beta/(1+(y(2)/(1+(aTC*exp(-Kdeg_atc*t).^2/Kx))).^2/Kd)-Kdeg_mRNA*y(1))*(K_max./(1+(K_max/n_bacteria-1)*exp(-(t./60)./r_bacteria)))*OD_amount*10e+10*v_bacteria*10e-15;
                dydt(2)=Ktrans*y(1)-Kdeg_tetR*y(2);
                dydt(3)=Ktrans_GSDMD*y(1)-Kdeg_GSDMD*y(3);
            end
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.betapromoterstrenthEditField.Value=0;
            app.KxdiffusionrateofATcEditField.Value=0;
            app.KddiffusionrateoftetREditField.Value=0;
            app.Ktrans_tetRtranslationrateoftetREditField.Value=0;
            app.Kdeg_mRNAdegradationrateofmRNAEditField.Value=0;
            app.Kdeg_tetRdegradationrateoftetREditField.Value=0;
            app.PtetcopynumberEditField.Value=0;
            app.ATc_minEditField.Value=0;
            app.ATc_maxEditField.Value=0;
            app.Ktrans_GSDMDtranslationrateofGSDMDEditField.Value=0;
            app.Kdeg_ATcdegradationrateofATcEditField.Value=0;
            app.Kdeg_GSDMDdegradationrateofGSDMDEditField.Value=0;
            app.NALabel.Text='N/A';
            app.UIAxes.cla;
            app.UIAxes_2.cla; 
            app.UIAxes_3.cla;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ParametersFunctionsTab = uitab(app.TabGroup);
            app.ParametersFunctionsTab.Title = 'Parameters&Functions';
            app.Panel = uipanel(app.ParametersFunctionsTab);
            app.Panel.Title = 'Panel';
            app.betapromoterstrenthEditFieldLabel = uilabel(app.Panel);
            app.betapromoterstrenthEditFieldLabel.Text = 'beta(promoter strenth)';
            app.betapromoterstrenthEditField = uieditfield(app.Panel, 'numeric');
            app.KxdiffusionrateofATcEditFieldLabel = uilabel(app.Panel);
            app.KxdiffusionrateofATcEditFieldLabel.Text = 'Kx(diffusion rate of ATc)';
            app.KxdiffusionrateofATcEditField = uieditfield(app.Panel, 'numeric');
            app.KddiffusionrateoftetREditFieldLabel = uilabel(app.Panel);
            app.KddiffusionrateoftetREditFieldLabel.Text = 'Kd(diffusion rate of tetR)';
            app.KddiffusionrateoftetREditField = uieditfield(app.Panel, 'numeric');
            app.Ktrans_tetRtranslationrateoftetREditFieldLabel = uilabel(app.Panel);
            app.Ktrans_tetRtranslationrateoftetREditFieldLabel.Text = 'Ktrans_tetR(translation rate of tetR)';
            app.Ktrans_tetRtranslationrateoftetREditField = uieditfield(app.Panel, 'numeric');
            app.Kdeg_tetRdegradationrateoftetREditFieldLabel = uilabel(app.Panel);
            app.Kdeg_tetRdegradationrateoftetREditFieldLabel.Text = 'Kdeg_tetR(degradation rate of tetR)';
            app.Kdeg_tetRdegradationrateoftetREditField = uieditfield(app.Panel, 'numeric');
            app.Kdeg_mRNAdegradationrateofmRNAEditFieldLabel = uilabel(app.Panel);
            app.Kdeg_mRNAdegradationrateofmRNAEditFieldLabel.Text = 'Kdeg_mRNA(degradation rate of mRNA)';
            app.Kdeg_mRNAdegradationrateofmRNAEditField = uieditfield(app.Panel, 'numeric');
            app.PtetcopynumberEditFieldLabel = uilabel(app.Panel);
            app.PtetcopynumberEditFieldLabel.Text = 'Ptet(copy number)';
            app.PtetcopynumberEditField = uieditfield(app.Panel, 'numeric');
            app.ATc_minEditFieldLabel = uilabel(app.Panel);
            app.ATc_minEditFieldLabel.Text = 'ATc_min';
            app.ATc_minEditField = uieditfield(app.Panel, 'numeric');
            app.ATc_minEditField.ValueDisplayFormat = '%.1f';
            app.ATc_maxEditFieldLabel = uilabel(app.Panel);
            app.ATc_maxEditFieldLabel.Text = 'ATc_max';
            app.ATc_maxEditField = uieditfield(app.Panel, 'numeric');
            app.ATc_maxEditField.ValueDisplayFormat = '%.1f';
            app.StartButton = uibutton(app.Panel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Kdeg_ATcdegradationrateofATcEditFieldLabel = uilabel(app.Panel);
            app.Kdeg_ATcdegradationrateofATcEditFieldLabel.Text = 'Kdeg_ATc(degradation rate of ATc)';
            app.Kdeg_ATcdegradationrateofATcEditField = uieditfield(app.Panel, 'numeric');
            app.Ktrans_GSDMDtranslationrateofGSDMDEditFieldLabel = uilabel(app.Panel);
            app.Ktrans_GSDMDtranslationrateofGSDMDEditFieldLabel.Text = 'Ktrans_GSDMD(translation rate of GSDMD)';
            app.Ktrans_GSDMDtranslationrateofGSDMDEditField = uieditfield(app.Panel, 'numeric');
            app.Kdeg_GSDMDdegradationrateofGSDMDEditFieldLabel = uilabel(app.Panel);
            app.Kdeg_GSDMDdegradationrateofGSDMDEditFieldLabel.Text = 'Kdeg_GSDMD(degradation rate of GSDMD)';
            app.Kdeg_GSDMDdegradationrateofGSDMDEditField = uieditfield(app.Panel, 'numeric');
            app.InitialODofbacteriaEditFieldLabel = uilabel(app.Panel);
            app.InitialODofbacteriaEditFieldLabel.Text = 'Initial OD of bacteria';
            app.InitialODofbacteriaEditField = uieditfield(app.Panel, 'numeric');
            app.Growthrateofbacteria1minEditFieldLabel = uilabel(app.Panel);
            app.Growthrateofbacteria1minEditFieldLabel.Text = 'Growth rate of bacteria (1/min)';
            app.Growthrateofbacteria1minEditField = uieditfield(app.Panel, 'numeric');
            app.MaxODofbacteriaEditFieldLabel = uilabel(app.Panel);
            app.MaxODofbacteriaEditFieldLabel.Text = 'Max OD of bacteria';
            app.MaxODofbacteriaEditField = uieditfield(app.Panel, 'numeric');
            app.BaceteriasamountperOD1010EditFieldLabel = uilabel(app.Panel);
            app.BaceteriasamountperOD1010EditFieldLabel.Text = 'Baceteria''s amount per OD (*10^10)';
            app.BaceteriasamountperOD1010EditField = uieditfield(app.Panel, 'numeric');
            app.Averragebacteriasvolumem3EditFieldLabel = uilabel(app.Panel);
            app.Averragebacteriasvolumem3EditFieldLabel.Text = 'Averrage bacteria''s volume (μm^3)';
            app.Averragebacteriasvolumem3EditField = uieditfield(app.Panel, 'numeric');
            app.Panel3 = uipanel(app.ParametersFunctionsTab);
            app.Panel3.Title = 'Panel3';
            app.FunctionPredictedLabel = uilabel(app.Panel3);
            app.FunctionPredictedLabel.Text = 'Function Predicted: ';
            app.NALabel = uilabel(app.Panel3);
            app.NALabel.Text = 'N/A';
            app.Panel4 = uipanel(app.ParametersFunctionsTab);
            app.Panel4.Title = 'Panel4';
            app.NoticeTextAreaLabel = uilabel(app.Panel4);
            app.NoticeTextAreaLabel.Text = 'Notice';
            app.NoticeTextArea = uitextarea(app.Panel4);
            app.NoticeTextArea.Editable = 'off';
            app.NoticeTextArea.Value = {'Without any special illustration, the default unit for concentration is nM and the default unit for time is s. '};
            app.FiguresTab = uitab(app.TabGroup);
            app.FiguresTab.Title = 'Figures';
            app.Panel2 = uipanel(app.FiguresTab);
            app.Panel2.Title = 'Panel2';
            app.UIAxes = uiaxes(app.Panel2);
            title(app.UIAxes, 'tetR-t')
            xlabel(app.UIAxes, ' time (s)')
            ylabel(app.UIAxes, 'Amount of tetR (nmol)')
            app.UIAxes_2 = uiaxes(app.Panel2);
            title(app.UIAxes_2, 'GSDMD-ATc')
            xlabel(app.UIAxes_2, 'Concerntration of ATc (nM)')
            ylabel(app.UIAxes_2, 'Max amount of GSDMD (mol)')
            app.UIAxes_3 = uiaxes(app.Panel2);
            title(app.UIAxes_3, 'GSDMD-t')
            xlabel(app.UIAxes_3, 'time (s)')
            ylabel(app.UIAxes_3, 'Amount of GSDMD (nmol)')
            app.UIAxes_4 = uiaxes(app.Panel2);
            title(app.UIAxes_4, 'Bacteria Growth')
            xlabel(app.UIAxes_4, 'time(min)')
            ylabel(app.UIAxes_4, 'OD600')
            app.ATcModellingSoftwareLabel = uilabel(app.UIFigure);
            app.ATcModellingSoftwareLabel.Text = 'ATc Modelling Software';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ATc_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end